classdef Fouricle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main_figure            matlab.ui.Figure
        Panel_2                matlab.ui.container.Panel
        segmentation_bottom    matlab.ui.control.Button
        run_bottom             matlab.ui.control.Button
        clear_bottom           matlab.ui.control.Button
        draw_bottom            matlab.ui.control.Button
        save_gif_bottom        matlab.ui.control.Button
        load_bottom            matlab.ui.control.Button
        save_data_bottom       matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        num_circles_spin       matlab.ui.control.Spinner
        Label_3                matlab.ui.control.Label
        interpolar_times_spin  matlab.ui.control.Spinner
        Label                  matlab.ui.control.Label
        speed_spin             matlab.ui.control.Spinner
        Label_2                matlab.ui.control.Label
        smoothing_param_spin   matlab.ui.control.Spinner
        Spinner_4Label         matlab.ui.control.Label
        draw_axes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        orgCurve            % 存储原始曲线数据
        curCurve            % 平滑并采样后的曲线数据，用于绘图
        num_circles         % 实际绘图时，所用的圆的个数，即length(curCurve)，即模型中的M
        num_points          % 原始数据曲线的点的个数，即length(orgCurve)，即模型中的N
        sampleRate          % 采样率，小于1时欠采样，大于1时过采样
        smoothingParam      % 平滑系数
        speed               % 绘图速度
    end
    methods (Access = private)
        % --- 数据平滑
        function [xfcn, yfcn] = SmoothingCurve(app, points)
            xfcn = fit((1:app.num_points)', points(:, 1), 'smoothingspline', 'SmoothingParam', app.smoothingParam);
            yfcn = fit((1:app.num_points)', points(:, 2), 'smoothingspline', 'SmoothingParam', app.smoothingParam);
        end
        % --- 获得圆数据和曲线数据
        function [x, y, u, v, radius] = getCircles(app)
            N = app.num_circles;
            [x, y] = deal(app.curCurve(:, 1), app.curCurve(:, 2));
            c = fft(complex(x, y), N) / N;              % 对复数序列做DFT
            [radius, index] = sort(abs(c), 'descend');  % 每个圆的半径(降序)和对应索引
            phase = angle(c(index));                    % 每个圆的初相角
            freq = index - 1;                           % 每个圆对应的频率
            [u, v] = deal(zeros(N + 1, N));             % 圆心横纵坐标，预分配内存
            for k = 0:N-1
                time = 2 * pi * k / N;
                u(2:N+1, k+1) = cumsum(radius .* cos(freq * time + phase));
                v(2:N+1, k+1) = cumsum(radius .* sin(freq * time + phase));
            end
        end
        % --- 更新曲线
        function updateCurve(app)
            points = app.orgCurve;
            if app.smoothingParam > 0
                plot(app.draw_axes, points(:, 1), points(:, 2), 'k--'), hold on;    % 绘制平滑前的数据
                [xfcn, yfcn] = app.SmoothingCurve(points);
                M = round(app.sampleRate * app.num_points); % 采样曲线点个数，四舍五入成整数
                points = Fouricle.sampler(xfcn, yfcn, app.num_points, M - 1);
                points = [points; points(1, :)];            % 增加一个点，使终点和起点一样
            end
            plot(app.draw_axes, points(:, 1), points(:, 2), 'Color', '#0072BD', 'Linewidth', 1.5), hold off;
            app.curCurve = points;                          % 使用的曲线数据
            app.num_circles = length(points);               % 绘图的圆个数
            app.num_circles_spin.Value = app.num_circles;   % 圆个数微调器上显示的值
        end
        % --- 绘制曲线(optional: 保存动画，如果filename非空)
        function draw_epicycle(app, h, ax, x, y, u, v, radius, filename)
            N = app.num_circles;
            pause_duration = exp(-log(10)/5 * app.speed);   % wait (?) seconds per step

            for k = 1:N
                cla(ax);    % 清除坐标区现在的曲线数据
                viscircles(ax, [u(1:N, k), v(1:N, k)], radius, 'Color', 0.5*[1 1 1], 'LineWidth', 0.1); hold(ax, 'on');
                line(ax, u(:, k), v(:, k), 'Color', 0.5*[1 1 1], 'LineWidth', 0.1); hold(ax, 'on'); % 两个圆心之间的连线
                line(ax, x(1:k), y(1:k), 'Color', '#0072BD', 'LineWidth', 1.5);  hold(ax, 'on');    % 已绘制的曲线数据
                plot(ax, u(end, k), v(end, k), 'or', 'MarkerFaceColor', 'r');  hold(ax, 'off');     % 当前绘制的曲线上的点
                drawnow;    % 更新图窗，使图像立即在屏幕上显示

                if isempty(filename)
                    pause(pause_duration);
                else
                    frame = getframe(h);
                    [imind, cm] = rgb2ind(frame2im(frame), 256);
                    if k == 1
                        imwrite(imind, cm, filename, 'gif', 'Loopcount', inf, 'DelayTime', 1 / 20);
                    else
                        imwrite(imind, cm, filename, 'gif', 'WriteMode', 'append', 'DelayTime', 1 / 20);
                    end
                end
            end
        end
    end
    methods (Static)
        % --- 采样数据(N长数组中尽可能均匀地采样M个数，可能欠采样可能过采样)
        function points = sampler(xfcn, yfcn, N, M)
            % fcn是曲线对应的拟合函数cfit对象，它输入t=1:N输出x或y
            t = linspace(1, N, M)';
            points = [xfcn(t), yfcn(t)];
        end
        % --- 生成动画图窗和坐标区
        function [h, ax] = getMovieFig()
            h = figure('Color', [1, 1, 1], 'Resize', 'off', 'HandleVisibility', 'callback');
            ax = axes('Parent', h);
            Fouricle.reset_axes(ax);    % Draw the result
        end
        % --- 重置坐标区属性
        function reset_axes(ax)
            cla(ax);                        % clear axes
            ax.XLim = [-1 1];
            ax.YLim = [-1 1];
            ax.XAxisLocation = 'bottom';
            ax.XColor = [0 0 0];
            ax.XTick = [];
            ax.YAxisLocation = 'left';
            ax.YColor = [0 0 0];
            ax.YTick = [];
            ax.BoxStyle = 'full';
            ax.LineWidth = 1.25;
            ax.ClippingStyle = 'rectangle';
            ax.FontSize = 12;
            ax.NextPlot = 'replace';
            ax.Box = 'on';
            ax.Tag = 'draw_axes';
            axis(ax, 'equal');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_draw_OpeningFcn(app, varargin)
            movegui(app.main_figure, 'onscreen');       % Ensure that the app appears on screen when run
            Fouricle.reset_axes(app.draw_axes);
            app.smoothingParam = app.smoothing_param_spin.Value;    % 平滑系数
            app.speed = app.speed_spin.Value;                       % 速度
            app.sampleRate = app.interpolar_times_spin.Value;       % 插值倍数
        end

        % Button pushed function: run_bottom
        function run_bottom_Callback(app, event)
            [x, y, u, v, radius] = app.getCircles();
            [h, ax] = Fouricle.getMovieFig();
            app.draw_epicycle(h, ax, x, y, u, v, radius, char.empty);   % 把filename传入一个空字符串
        end

        % Button pushed function: clear_bottom
        function clear_bottom_Callback(app, event)
            cla(app.draw_axes);         % clear axes
            Fouricle.reset_axes(app.draw_axes);
        end

        % Value changing function: smoothing_param_spin
        function SmoothSpinnerValueChanging(app, event)
            app.smoothingParam = event.Value;
            app.updateCurve();
        end

        % Value changing function: speed_spin
        function SpeedSpinnerValueChanging(app, event)
            app.speed = event.Value;
        end

        % Value changed function: smoothing_param_spin
        function SmoothSpinnerValueChanged(app, event)
            app.smoothingParam = app.smoothing_param_spin.Value;
            app.updateCurve();
        end

        % Value changed function: speed_spin
        function SpeedSpinnerValueChanged(app, event)
            app.speed = app.speed_spin.Value;
        end

        % Value changed function: interpolar_times_spin
        function interpolar_times_spinValueChanged(app, event)
            app.sampleRate = app.interpolar_times_spin.Value;
            app.updateCurve();
        end

        % Value changing function: interpolar_times_spin
        function interpolar_times_spinValueChanging(app, event)
            app.sampleRate = event.Value;
            app.updateCurve();
        end

        % Button pushed function: load_bottom
        function load_bottomButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mat'});
            if filename == 0, return; end
            points = importdata(fullfile(path, filename));
            points = normalize([points.X, points.Y], 'Range', [-0.9, 0.9]);

            app.orgCurve = points;
            app.num_points = length(points);
            app.updateCurve();
        end

        % Button pushed function: draw_bottom
        function draw_bottomButtonPushed(app, event)
            Fouricle.reset_axes(app.draw_axes);     % 防止用户在绘图之前拖动坐标区，再重置一次
            % 创建ROI对象用于绘图，它只是在显示上把起点和终点连起来
            matlabVersion = str2double(ver("MATLAB").Release(3:6));
            if matlabVersion < 2021
                temp_ax = axes('Parent', app.main_figure);
                Fouricle.reset_axes(temp_ax);
                temp_ax.Units = 'pixels';
                temp_ax.OuterPosition = [11 12 470 450];
                temp_ax.PlotBoxAspectRatio = [2.0889 2 1];
                hFH = drawfreehand(temp_ax, 'DrawingArea', [-1, -1, 2, 2]);
            else
                hFH = drawfreehand(app.draw_axes, 'DrawingArea', [-1, -1, 2, 2]);
            end
            if isempty(hFH) || length(hFH.Position) <= 5, return; end   % Bail out
            points = [hFH.Position; hFH.Position(1, :)];    % 在数据中把起点和终点连起来
            delete(hFH);                        % 用完之后把ROI对象删除
            if exist("temp_ax", "var"), delete(temp_ax); end
            app.orgCurve = points;              % 原始的曲线数据
            app.num_points = length(points);    % 原始曲线点个数

            app.updateCurve();
        end

        % Button pushed function: save_data_bottom
        function save_data_bottomButtonPushed(app, event)
            X = app.orgCurve(:, 1);             %#ok<NASGU> 
            Y = app.orgCurve(:, 2);             %#ok<NASGU> 
            uisave({'X', 'Y'});

            msg = 'Data saved successfully!';
            title = 'Confirm save';
            uiconfirm(app.main_figure, msg, title, 'Options', {'OK!'}, 'Icon', 'success');
        end

        % Button pushed function: save_gif_bottom
        function save_gif_bottomButtonPushed(app, event)
            filename = uiputfile('filename.gif');
            [x, y, u, v, radius] = app.getCircles();
            [h, ax] = Fouricle.getMovieFig();
            app.draw_epicycle(h, ax, x, y, u, v, radius, filename);

            msg = 'GIF saved successfully!';
            title = 'Confirm save';
            uiconfirm(app.main_figure, msg, title, 'Options', {'OK!'}, 'Icon', 'success');
        end

        % Button pushed function: segmentation_bottom
        function segmentation_bottomButtonPushed(app, event)
            msg = 'This feature may be available in the next version.';
            title = 'Not implemented';
            uiconfirm(app.main_figure, msg, title, 'Options', {'Expect!'}, 'Icon', 'error');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main_figure = uifigure('Visible', 'off');
            app.main_figure.Name = 'Fouricle';
            app.main_figure.Tag = 'figure1';
            app.draw_axes = uiaxes(app.main_figure);
            title(app.draw_axes, '在下方坐标区绘制你的曲线')
            app.draw_axes.XLim = [-1 1];
            app.draw_axes.YLim = [-1 1];
            app.draw_axes.XTick = [];
            app.draw_axes.YTick = [];
            app.draw_axes.Tag = 'draw_axes';
            app.Panel = uipanel(app.main_figure);
            app.Panel.Title = '控制面板';
            app.Spinner_4Label = uilabel(app.Panel);
            app.Spinner_4Label.Tooltip = {'取值在0到1之间，值越高则原式数据对其的影响越大，值为0时表示无平滑操作。'};
            app.Spinner_4Label.Text = '平滑系数';
            app.smoothing_param_spin = uispinner(app.Panel);
            app.smoothing_param_spin.Step = 0.05;
            app.smoothing_param_spin.ValueChangingFcn = createCallbackFcn(app, @SmoothSpinnerValueChanging, true);
            app.smoothing_param_spin.Limits = [0 1];
            app.smoothing_param_spin.ValueChangedFcn = createCallbackFcn(app, @SmoothSpinnerValueChanged, true);
            app.smoothing_param_spin.Tooltip = {'取值在0到1之间，值越高则原式数据对其的影响越大，值为0时表示无平滑操作。'};
            app.smoothing_param_spin.Value = 0.3;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Tooltip = {'速度为10时，每绘制一个时间点的图像，延迟0.01秒。速度为1时，约延迟1.6秒，呈对数衰减'};
            app.Label_2.Text = '绘图速度';
            app.speed_spin = uispinner(app.Panel);
            app.speed_spin.ValueChangingFcn = createCallbackFcn(app, @SpeedSpinnerValueChanging, true);
            app.speed_spin.Limits = [1 10];
            app.speed_spin.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.speed_spin.Tooltip = {'速度为10时，每绘制一个时间点的图像，延迟0.01秒。速度为1时，约延迟1.6秒，呈对数衰减'};
            app.speed_spin.Value = 10;
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'对平滑后的曲线进行采样，该值表示采样点个数为原始数据点个数的多少倍。若平滑系数为0，则该数值不起作用'};
            app.Label.Text = '采样倍数';
            app.interpolar_times_spin = uispinner(app.Panel);
            app.interpolar_times_spin.Step = 0.1;
            app.interpolar_times_spin.ValueChangingFcn = createCallbackFcn(app, @interpolar_times_spinValueChanging, true);
            app.interpolar_times_spin.Limits = [0.1 3];
            app.interpolar_times_spin.ValueChangedFcn = createCallbackFcn(app, @interpolar_times_spinValueChanged, true);
            app.interpolar_times_spin.Tooltip = {'对平滑后的曲线进行采样，该值表示采样点个数为原始数据点个数的多少倍。若平滑系数为0，则该数值不起作用'};
            app.interpolar_times_spin.Value = 0.5;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Tooltip = {'实际使用的圆的数量，该值不可调节'};
            app.Label_3.Text = '圆数量';
            app.num_circles_spin = uispinner(app.Panel);
            app.num_circles_spin.Editable = 'off';
            app.num_circles_spin.Enable = 'off';
            app.num_circles_spin.Tooltip = {'实际使用的圆的数量，该值不可调节'};
            app.num_circles_spin.Value = 1;
            app.Panel_2 = uipanel(app.main_figure);
            app.Panel_2.Title = '选项卡';
            app.save_data_bottom = uibutton(app.Panel_2, 'push');
            app.save_data_bottom.ButtonPushedFcn = createCallbackFcn(app, @save_data_bottomButtonPushed, true);
            app.save_data_bottom.Tag = 'button_draw_reset';
            app.save_data_bottom.Text = '保存数据';
            app.load_bottom = uibutton(app.Panel_2, 'push');
            app.load_bottom.ButtonPushedFcn = createCallbackFcn(app, @load_bottomButtonPushed, true);
            app.load_bottom.Tag = 'button_draw_reset';
            app.load_bottom.Text = '加载数据';
            app.save_gif_bottom = uibutton(app.Panel_2, 'push');
            app.save_gif_bottom.ButtonPushedFcn = createCallbackFcn(app, @save_gif_bottomButtonPushed, true);
            app.save_gif_bottom.Tag = 'button_draw_reset';
            app.save_gif_bottom.Tooltip = {'自动生成GIF动画'};
            app.save_gif_bottom.Text = '保存动画';
            app.draw_bottom = uibutton(app.Panel_2, 'push');
            app.draw_bottom.ButtonPushedFcn = createCallbackFcn(app, @draw_bottomButtonPushed, true);
            app.draw_bottom.Tag = 'button_draw_reset';
            app.draw_bottom.Tooltip = {'在坐标区中点击鼠标，并按住不放绘制曲线，尽量使起点和终点一致'};
            app.draw_bottom.Text = '手绘曲线';
            app.clear_bottom = uibutton(app.Panel_2, 'push');
            app.clear_bottom.ButtonPushedFcn = createCallbackFcn(app, @clear_bottom_Callback, true);
            app.clear_bottom.Tag = 'button_draw_reset';
            app.clear_bottom.Tooltip = {'清除当前坐标区数据'};
            app.clear_bottom.Text = 'Clear';
            app.run_bottom = uibutton(app.Panel_2, 'push');
            app.run_bottom.ButtonPushedFcn = createCallbackFcn(app, @run_bottom_Callback, true);
            app.run_bottom.Tag = 'button_draw_ok';
            app.run_bottom.Tooltip = {'开始绘图'};
            app.run_bottom.Text = 'Run!';
            app.segmentation_bottom = uibutton(app.Panel_2, 'push');
            app.segmentation_bottom.ButtonPushedFcn = createCallbackFcn(app, @segmentation_bottomButtonPushed, true);
            app.segmentation_bottom.Tag = 'button_draw_reset';
            app.segmentation_bottom.Tooltip = {'还没实现，后面几个版本可能推出'};
            app.segmentation_bottom.Text = '图像分割';
            % Show the figure after all components are created
            app.main_figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fouricle(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.main_figure)

                % Execute the startup function
                runStartupFcn(app, @(app)gui_draw_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.main_figure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main_figure)
        end
    end
end