classdef gui_draw_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main_figure            matlab.ui.Figure
        Panel_2                matlab.ui.container.Panel
        segmentation_bottom    matlab.ui.control.Button
        run_bottom             matlab.ui.control.Button
        clear_bottom           matlab.ui.control.Button
        draw_bottom            matlab.ui.control.Button
        save_gif_bottom        matlab.ui.control.Button
        load_bottom            matlab.ui.control.Button
        save_data_bottom       matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        num_circles_spin       matlab.ui.control.Spinner
        Label_3                matlab.ui.control.Label
        interpolar_times_spin  matlab.ui.control.Spinner
        Label                  matlab.ui.control.Label
        speed_spin             matlab.ui.control.Spinner
        Label_2                matlab.ui.control.Label
        smoothing_param_spin   matlab.ui.control.Spinner
        Spinner_4Label         matlab.ui.control.Label
        draw_axes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        curve
        speed
        smoothingParam
        precision
        num_circles
    end
    methods (Access = private)
        % --- 数据平滑
        function points = smoothingCurve(app, points)
            N = length(points);
            steps = linspace(1, N, N * app.precision)';
            fx = fit((1:N)', points(:, 1), 'smoothingspline', 'SmoothingParam', app.smoothingParam);
            fy = fit((1:N)', points(:, 2), 'smoothingspline', 'SmoothingParam', app.smoothingParam);
            points = [fx(steps), fy(steps)];
            points = [points; points(1,:)];     % 起点和终点连起来
        end
        % --- 绘图主程序
        function h = fourier_drawing(app, filename)
            % Parameters
            [x, y] = deal(app.curve(:, 1), app.curve(:, 2));
            pause_duration = exp(-log(10)/5 * app.speed);   % wait (?) seconds per step, range = (0.01 to 1) when speed = (10 to 1)
            N = length(x);

            % Downsample the curve if required
            if app.num_circles < N
                index = round(linspace(1, N, app.num_circles));
                x = x(index, :);
                y = y(index, :);
                N = app.num_circles;
            end

            % 数据准备
            X = fft(complex(x, y), N) / N;              % 对复数序列做DFT
            [radius, index] = sort(abs(X), 'descend');  % 每个圆的半径(降序)
            phase = angle(X(index));                    % 每个圆的初项
            freq = index - 1;                           % 每个圆对应的频率

            [u, v] = deal(zeros(N + 1, N));
            for step = 0:N-1
                time = step * 2 * pi / N;
                u(2:N+1, step+1) = cumsum(radius .* cos(freq * time + phase));
                v(2:N+1, step+1) = cumsum(radius .* sin(freq * time + phase));
            end

            % plotting
            h = uifigure('Color', [1, 1, 1]);
            h.Resize = 'off';
            h.HandleVisibility = 'callback';
            ax = axes('Parent', h);
            gui_draw_App.reset_axes(ax);    % Draw the result
            if ~isempty(filename), gui_draw_App.save_movie(h, ax, x, y, u, v, radius, filename); return; end

            for step = 1:N
                cla(ax), hold(ax, 'on');    % clearing axes
                viscircles(ax, [u(1:N, step), v(1:N, step)], radius, Color=0.5*[1 1 1], LineWidth=0.1); hold(ax, 'on');
                plot(ax, u(:, step), v(:, step), Color=0.5*[1 1 1], LineWidth=0.1); hold(ax, 'on');
                plot(ax, x(1:step), y(1:step), Color='#0072BD', LineWidth=1.5);  hold(ax, 'on');    % curve
                plot(ax, u(end, step), v(end, step), 'or', MarkerFaceColor='r');  hold(ax, 'off');  % current point
                drawnow;
                pause(pause_duration);
            end
        end
    end
    methods (Static)
        % --- 重置坐标区属性
        function reset_axes(ax)
            ax.XLim = [-1 1];
            ax.YLim = [-1 1];
            ax.XAxisLocation = 'bottom';
            ax.XColor = [0 0 0];
            ax.XTick = [];
            ax.YAxisLocation = 'left';
            ax.YColor = [0 0 0];
            ax.YTick = [];
            ax.BoxStyle = 'full';
            ax.LineWidth = 1.25;
            ax.ClippingStyle = 'rectangle';
            ax.FontSize = 12;
            ax.NextPlot = 'replace';
            ax.Box = 'on';
            ax.Tag = 'draw_axes';
            axis(ax, 'equal');
        end
        % --- 保存动画
        function save_movie(h, ax, x, y, u, v, radius, filename)
            N = length(x);
            for step = 1:N
                cla(ax), hold(ax, 'on');    % clearing axes
                viscircles(ax, [u(1:N, step), v(1:N, step)], radius, Color=0.5*[1 1 1], LineWidth=0.1); hold(ax, 'on');
                plot(ax, u(:, step), v(:, step), Color=0.5*[1 1 1], LineWidth=0.1); hold(ax, 'on');
                plot(ax, x(1:step), y(1:step), Color='#0072BD', LineWidth=1.5);  hold(ax, 'on');    % curve
                plot(ax, u(end, step), v(end, step), 'or', MarkerFaceColor='r');  hold(ax, 'off');  % current point
                drawnow;

                frame = getframe(h);
                im = frame2im(frame);
                [imind, cm] = rgb2ind(im, 256);
                if step == 1
                    imwrite(imind, cm, filename, 'gif', 'Loopcount', inf, 'DelayTime', 1 / 30);
                else
                    imwrite(imind, cm, filename, 'gif', 'WriteMode', 'append', 'DelayTime', 1 / 30);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui_draw_OpeningFcn(app, varargin)
            movegui(app.main_figure, 'onscreen');   % Ensure that the app appears on screen when run
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);

            handles.output = hObject;           % 选择默认的命令行作为main的输出
            guidata(hObject, handles);          % 更新句柄们的结构
            gui_draw_App.reset_axes(app.draw_axes);

            app.smoothingParam = app.smoothing_param_spin.Value;    % 平滑系数
            app.speed = app.speed_spin.Value;                       % 速度
            app.precision = app.interpolar_times_spin.Value;        % 插值倍数
        end

        % Button pushed function: run_bottom
        function run_bottom_Callback(app, event)
            app.fourier_drawing(char.empty);    % 把filename传入一个空字符串
        end

        % Button pushed function: clear_bottom
        function clear_bottom_Callback(app, event)
            cla(app.draw_axes);         % clear axes
            gui_draw_App.reset_axes(app.draw_axes);
        end

        % Value changing function: smoothing_param_spin
        function SmoothSpinnerValueChanging(app, event)
            app.smoothingParam = event.Value;
        end

        % Value changing function: speed_spin
        function SpeedSpinnerValueChanging(app, event)
            app.speed = event.Value;
        end

        % Value changed function: smoothing_param_spin
        function SmoothSpinnerValueChanged(app, event)
            app.smoothingParam = app.smoothing_param_spin.Value;
        end

        % Value changed function: speed_spin
        function SpeedSpinnerValueChanged(app, event)
            app.speed = app.speed_spin.Value;
        end

        % Value changed function: interpolar_times_spin
        function interpolar_times_spinValueChanged(app, event)
            app.precision = app.interpolar_times_spin.Value;
        end

        % Value changing function: interpolar_times_spin
        function interpolar_times_spinValueChanging(app, event)
            app.precision = event.Value;
        end

        % Value changed function: num_circles_spin
        function num_circles_spinValueChanged(app, event)
            app.numcircles = app.num_circles_spin.Value;
        end

        % Value changing function: num_circles_spin
        function num_circles_spinValueChanging(app, event)
            app.num_circles = event.Value;
        end

        % Button pushed function: load_bottom
        function load_bottomButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mat'});
            full_filename = fullfile(path, filename);
            points = importdata(full_filename);
            app.curve = normalize([points.X, points.Y], 'Range', [-0.9, 0.9]);

            if app.smoothingParam > 0
                plot(app.draw_axes, app.curve(:, 1), app.curve(:, 2), 'k--'), hold on;  % 绘制平滑前的数据
                points = smoothingCurve(app, app.curve);
            end
            plot(app.draw_axes, points(:, 1), points(:, 2), Color='#0072BD', Linewidth=1.5);
            app.num_circles = length(app.curve);            % 圆个数
            app.num_circles_spin.Value = app.num_circles;   % 圆个数微调器上显示的值
        end

        % Button pushed function: draw_bottom
        function draw_bottomButtonPushed(app, event)
            gui_draw_App.reset_axes(app.draw_axes);     % 防止用户在绘图之前拖动坐标区，再重置一次
            % 创建ROI对象用于绘图，会自动把起点和终点连起来
            hFH = drawfreehand(app.draw_axes, 'closed', true, 'DrawingArea', [-1, -1, 2, 2]);
            if isempty(hFH), return; end    % Bail out
            points = hFH.Position;
            delete(hFH);                    % 用完之后把ROI对象删除
            if app.smoothingParam > 0
                plot(app.draw_axes, points(:, 1), points(:, 2), 'k--'), hold on;    % 绘制平滑前的数据
                points = smoothingCurve(app, points);
            end
            plot(app.draw_axes, points(:, 1), points(:, 2), Color='#0072BD', Linewidth=1.5);
            app.curve = points;                             % 曲线数据
            app.num_circles = length(points);               % 圆个数
            app.num_circles_spin.Value = app.num_circles;   % 圆个数微调器上显示的值
        end

        % Button pushed function: save_data_bottom
        function save_data_bottomButtonPushed(app, event)
            X = app.curve(:, 1);
            Y = app.curve(:, 2);
            uisave({'X', 'Y'});
        end

        % Button pushed function: save_gif_bottom
        function save_gif_bottomButtonPushed(app, event)
            filename = uiputfile('filename.gif');
            h = app.fourier_drawing(filename);

            msg = 'GIF saved successfully!';
            title = 'Confirm save';
            uiconfirm(h, msg, title, 'Options', {'OK!'}, 'Icon', 'success');
        end

        % Button pushed function: segmentation_bottom
        function segmentation_bottomButtonPushed(app, event)
            msg = 'This feature may be available in the next version.';
            title = 'Not implemented';
            uiconfirm(app.main_figure, msg, title, 'Options', {'Expect!'}, 'Icon', 'error');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main_figure = uifigure('Visible', 'off');
            app.main_figure.Name = 'Draw your custom line';
            app.main_figure.Tag = 'figure1';
            app.draw_axes = uiaxes(app.main_figure);
            title(app.draw_axes, '在下方坐标区绘制你的曲线')
            app.draw_axes.XLim = [-1 1];
            app.draw_axes.YLim = [-1 1];
            app.draw_axes.XTick = [];
            app.draw_axes.YTick = [];
            app.draw_axes.Tag = 'draw_axes';
            app.Panel = uipanel(app.main_figure);
            app.Panel.Title = '控制面板';
            app.Spinner_4Label = uilabel(app.Panel);
            app.Spinner_4Label.Text = '平滑系数';
            app.smoothing_param_spin = uispinner(app.Panel);
            app.smoothing_param_spin.Step = 0.05;
            app.smoothing_param_spin.ValueChangingFcn = createCallbackFcn(app, @SmoothSpinnerValueChanging, true);
            app.smoothing_param_spin.Limits = [0 1];
            app.smoothing_param_spin.ValueChangedFcn = createCallbackFcn(app, @SmoothSpinnerValueChanged, true);
            app.smoothing_param_spin.Value = 0.3;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '绘图速度';
            app.speed_spin = uispinner(app.Panel);
            app.speed_spin.ValueChangingFcn = createCallbackFcn(app, @SpeedSpinnerValueChanging, true);
            app.speed_spin.Limits = [1 10];
            app.speed_spin.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.speed_spin.Value = 10;
            app.Label = uilabel(app.Panel);
            app.Label.Text = '插值倍数';
            app.interpolar_times_spin = uispinner(app.Panel);
            app.interpolar_times_spin.Step = 0.1;
            app.interpolar_times_spin.ValueChangingFcn = createCallbackFcn(app, @interpolar_times_spinValueChanging, true);
            app.interpolar_times_spin.Limits = [1 3];
            app.interpolar_times_spin.ValueChangedFcn = createCallbackFcn(app, @interpolar_times_spinValueChanged, true);
            app.interpolar_times_spin.Value = 1;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '圆数量';
            app.num_circles_spin = uispinner(app.Panel);
            app.num_circles_spin.ValueChangingFcn = createCallbackFcn(app, @num_circles_spinValueChanging, true);
            app.num_circles_spin.Limits = [1 Inf];
            app.num_circles_spin.ValueChangedFcn = createCallbackFcn(app, @num_circles_spinValueChanged, true);
            app.num_circles_spin.Value = 1;
            app.Panel_2 = uipanel(app.main_figure);
            app.Panel_2.Title = '选项卡';
            app.save_data_bottom = uibutton(app.Panel_2, 'push');
            app.save_data_bottom.ButtonPushedFcn = createCallbackFcn(app, @save_data_bottomButtonPushed, true);
            app.save_data_bottom.Tag = 'button_draw_reset';
            app.save_data_bottom.Text = '保存数据';
            app.load_bottom = uibutton(app.Panel_2, 'push');
            app.load_bottom.ButtonPushedFcn = createCallbackFcn(app, @load_bottomButtonPushed, true);
            app.load_bottom.Tag = 'button_draw_reset';
            app.load_bottom.Text = '加载数据';
            app.save_gif_bottom = uibutton(app.Panel_2, 'push');
            app.save_gif_bottom.ButtonPushedFcn = createCallbackFcn(app, @save_gif_bottomButtonPushed, true);
            app.save_gif_bottom.Tag = 'button_draw_reset';
            app.save_gif_bottom.Text = '保存动画';
            app.draw_bottom = uibutton(app.Panel_2, 'push');
            app.draw_bottom.ButtonPushedFcn = createCallbackFcn(app, @draw_bottomButtonPushed, true);
            app.draw_bottom.Tag = 'button_draw_reset';
            app.draw_bottom.Text = '手绘曲线';
            app.clear_bottom = uibutton(app.Panel_2, 'push');
            app.clear_bottom.ButtonPushedFcn = createCallbackFcn(app, @clear_bottom_Callback, true);
            app.clear_bottom.Tag = 'button_draw_reset';
            app.clear_bottom.Text = 'Clear';
            app.run_bottom = uibutton(app.Panel_2, 'push');
            app.run_bottom.ButtonPushedFcn = createCallbackFcn(app, @run_bottom_Callback, true);
            app.run_bottom.Tag = 'button_draw_ok';
            app.run_bottom.Text = 'Run!';
            app.segmentation_bottom = uibutton(app.Panel_2, 'push');
            app.segmentation_bottom.ButtonPushedFcn = createCallbackFcn(app, @segmentation_bottomButtonPushed, true);
            app.segmentation_bottom.Tag = 'button_draw_reset';
            app.segmentation_bottom.Text = '图像分割';
            % Show the figure after all components are created
            app.main_figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_draw_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.main_figure)

                % Execute the startup function
                runStartupFcn(app, @(app)gui_draw_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.main_figure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main_figure)
        end
    end
end